classdef alineaE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RedesNeuronaisGUIUIFigure      matlab.ui.Figure
        FunodeTreinoDropDownLabel      matlab.ui.control.Label
        FunodeTreinoDropDown           matlab.ui.control.DropDown
        RedesNeuronaisLabel            matlab.ui.control.Label
        TrabalhoPrticodeCRLabel        matlab.ui.control.Label
        FunodeAtivaoDropDownLabel      matlab.ui.control.Label
        FunodeAtivaoDropDown           matlab.ui.control.DropDown
        FunodeDivisoDropDownLabel      matlab.ui.control.Label
        FunodeDivisoDropDown           matlab.ui.control.DropDown
        TreinarButton                  matlab.ui.control.Button
        GravarButton                   matlab.ui.control.Button
        CarregarButton                 matlab.ui.control.Button
        pocasSliderLabel               matlab.ui.control.Label
        pocasSlider                    matlab.ui.control.Slider
        Descricao                      matlab.ui.control.Label
        TopologiaDropDownLabel         matlab.ui.control.Label
        TopologiaDropDown              matlab.ui.control.DropDown
        PastadasImagensEditFieldLabel  matlab.ui.control.Label
        PastadasImagensEditField       matlab.ui.control.EditField
        LimparButton                   matlab.ui.control.Button
        SegmentaodoDatasetPanel        matlab.ui.container.Panel
        TreinoEditFieldLabel           matlab.ui.control.Label
        TreinoEditField                matlab.ui.control.NumericEditField
        ValidaoLabel                   matlab.ui.control.Label
        ValidaoEditField               matlab.ui.control.NumericEditField
        TesteLabel                     matlab.ui.control.Label
        TesteEditField                 matlab.ui.control.NumericEditField
        CarregarImagemButton           matlab.ui.control.Button
        TestarButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Net
        Imagem
        Out
        Target
        Figuras
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            cla(app.UIAxes);
            app.Figuras = ["Círculo" "Papagaio" "Paralelogramo" "Quadrado" "Trapézio" "Triângulo"];
            app.Figuras = (app.Figuras).';
        end

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
            folder = app.PastadasImagensEditField.Value;
            [input, tamanho] = process_images(folder);
            target = gen_target(tamanho);
            app.Target = target;
            
            type = app.TopologiaDropDown.Value;
            transferFcn = app.FunodeAtivaoDropDown.Value;
            trainFcn = app.FunodeTreinoDropDown.Value;
            divideFcn = app.FunodeDivisoDropDown.Value;
            epochs = app.pocasSlider.Value;
            
            net = loadNet(type, transferFcn, trainFcn, divideFcn, epochs);
            app.TreinarButton.Enable = 'off';
            app.CarregarButton.Enable = 'off';
            app.GravarButton.Enable = 'off';
            app.TestarButton.Enable = 'off';
            [net, tr] =  train(net, input, target);
            app.Net = net;
            app.TreinarButton.Enable = 'on';
            app.CarregarButton.Enable = 'on';
            app.GravarButton.Enable = 'on';
            app.TestarButton.Enable = 'on';
        end

        % Button pushed function: CarregarButton
        function CarregarButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if(path ~= 0)
                load(append(path, "/", file), "net");
                app.Net = net;
            end
            
            switch(app.Net.userdata)
                case "Feedforward (10 neurónios)"
                    app.TopologiaDropDown.Value = "Feedforward (10 neurónios)";
                case "Pattern (10 neurónios)"
                    app.TopologiaDropDown.Value = "Pattern (10 neurónios)";
                case "Fitting (10 neurónios)"
                    app.TopologiaDropDown.Value = "Fitting (10 neurónios)";
                otherwise
                    app.TopologiaDropDown.Value = "Feedforward (10 neurónios)";
                    msgbox('O programa não reconhece essa arquitetura de rede neuronal', 'Error', 'Error');
                    app.Net = [];
                    return;
            end
            app.FunodeAtivaoDropDown.Value = app.Net.layers{1}.transferFcn;
            app.FunodeTreinoDropDown.Value = app.Net.trainFcn;
            app.FunodeDivisoDropDown.Value = app.Net.divideFcn;
            app.pocasSlider.Value = app.Net.trainParam.epochs;
            app.TestarButton.Enable = 'on';
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            cla(app.UIAxes);
            app.PastadasImagensEditField.Value = "imagens/train/*/*.png";
            app.TopologiaDropDown.Value = "Feedforward (10 neurónios)";
            app.FunodeTreinoDropDown.Value = "trainlm";
            app.FunodeAtivaoDropDown.Value = "logsig";
            app.FunodeDivisoDropDown.Value = "dividerand";
            app.pocasSlider.Value = 1000;
            app.TreinoEditField.Value = 70;
            app.ValidaoEditField.Value = 15;
            app.TesteEditField.Value = 15;
            app.Net = [];
            app.Imagem = [];
            app.Target = [];
            app.Out = [];
            app.TreinarButton.Enable = 'on';
            app.CarregarButton.Enable = 'on';
            app.GravarButton.Enable = 'off';
            app.TestarButton.Enable = 'off';
            app.Descricao.Text = "";
        end

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            [file, path] = uigetfile('*.png');
            if(path ~= 0)
                app.Imagem = imread(append(path, "/", file));
                app.Imagem = app.Imagem(:,:,1);
                imshow(app.Imagem, 'parent', app.UIAxes);
                app.Imagem = imresize(app.Imagem, [32 32]);
                app.Imagem = imbinarize(app.Imagem);
                app.Imagem = app.Imagem(:);
            end
        end

        % Button pushed function: TestarButton
        function TestarButtonPushed(app, event)
            if(isempty(app.Net))
                msgbox('Não existe nenhuma rede neuronal ativa', 'Error', 'Error');
            elseif(isempty(app.Imagem))
                msgbox('Não foi carregada nenhuma imagem', 'Error', 'Error');
            else
                out = sim(app.Net, app.Imagem);
                [M, i] = max(out);
                app.Descricao.Text = sprintf("A imagem introduzida é considerada um %s com uma precisão de %2.2f%%", upper(app.Figuras(i)), (M * 100));
                app.Out = out;
            end
        end

        % Callback function
        function MatrizdeConfusoButtonPushed(app, event)
            plotconfusion(app.Target, app.Out);
        end

        % Button pushed function: GravarButton
        function GravarButtonPushed(app, event)
            if(~isempty(app.Net))
                [file, path] = uiputfile({'*.mat'}, "Guardar rede neuronal", "net.mat");
                if(path ~= 0)
                   net = app.Net;
                   save(append(path, "/", file), 'net');
                end
            else
                msgbox('Não existe nenhuma rede para ser guardada', 'Error','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RedesNeuronaisGUIUIFigure = uifigure('Visible', 'off');
            app.RedesNeuronaisGUIUIFigure.Name = 'Redes Neuronais - GUI';
            app.FunodeTreinoDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.FunodeTreinoDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.FunodeTreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainscg', 'trainoss', 'traingdx', 'traingd'};
            app.FunodeTreinoDropDown.Value = 'trainlm';
            app.RedesNeuronaisLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.RedesNeuronaisLabel.Text = 'Redes Neuronais';
            app.TrabalhoPrticodeCRLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.TrabalhoPrticodeCRLabel.Text = 'Trabalho Prático de CR';
            app.FunodeAtivaoDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.FunodeAtivaoDropDownLabel.Text = 'Função de Ativação';
            app.FunodeAtivaoDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.FunodeAtivaoDropDown.Items = {'logsig', 'hardlim', 'purelin', 'softmax', 'tansig', 'tribas'};
            app.FunodeAtivaoDropDown.Value = 'logsig';
            app.FunodeDivisoDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.FunodeDivisoDropDownLabel.Text = 'Função de Divisão';
            app.FunodeDivisoDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.FunodeDivisoDropDown.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.FunodeDivisoDropDown.Value = 'dividerand';
            app.TreinarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Text = 'Treinar';
            app.GravarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.GravarButton.ButtonPushedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.GravarButton.Enable = 'off';
            app.GravarButton.Text = 'Gravar';
            app.CarregarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.CarregarButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarButtonPushed, true);
            app.CarregarButton.Text = 'Carregar';
            app.pocasSliderLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.pocasSliderLabel.Text = 'Épocas';
            app.pocasSlider = uislider(app.RedesNeuronaisGUIUIFigure);
            app.pocasSlider.Limits = [0 1000];
            app.pocasSlider.Value = 1000;
            app.Descricao = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.Descricao.Text = '';
            app.TopologiaDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.TopologiaDropDownLabel.Text = 'Topologia';
            app.TopologiaDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.TopologiaDropDown.Items = {'Feedforward (10 neurónios)', 'Pattern (10 neurónios)', 'Fitting (10 neurónios)'};
            app.TopologiaDropDown.Value = 'Feedforward (10 neurónios)';
            app.PastadasImagensEditFieldLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.PastadasImagensEditFieldLabel.Text = 'Pasta das Imagens';
            app.PastadasImagensEditField = uieditfield(app.RedesNeuronaisGUIUIFigure, 'text');
            app.PastadasImagensEditField.Value = 'imagens/train/*/*.png';
            app.LimparButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.SegmentaodoDatasetPanel = uipanel(app.RedesNeuronaisGUIUIFigure);
            app.SegmentaodoDatasetPanel.Title = 'Segmentação do Dataset';
            app.TreinoEditFieldLabel = uilabel(app.SegmentaodoDatasetPanel);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.TreinoEditField = uieditfield(app.SegmentaodoDatasetPanel, 'numeric');
            app.TreinoEditField.Limits = [0 100];
            app.TreinoEditField.RoundFractionalValues = 'on';
            app.TreinoEditField.ValueDisplayFormat = '%.0f';
            app.TreinoEditField.Value = 70;
            app.ValidaoLabel = uilabel(app.SegmentaodoDatasetPanel);
            app.ValidaoLabel.Text = 'Validação ';
            app.ValidaoEditField = uieditfield(app.SegmentaodoDatasetPanel, 'numeric');
            app.ValidaoEditField.Limits = [0 100];
            app.ValidaoEditField.RoundFractionalValues = 'on';
            app.ValidaoEditField.ValueDisplayFormat = '%.0f';
            app.ValidaoEditField.Value = 15;
            app.TesteLabel = uilabel(app.SegmentaodoDatasetPanel);
            app.TesteLabel.Text = 'Teste ';
            app.TesteEditField = uieditfield(app.SegmentaodoDatasetPanel, 'numeric');
            app.TesteEditField.Limits = [0 100];
            app.TesteEditField.RoundFractionalValues = 'on';
            app.TesteEditField.ValueDisplayFormat = '%.0f';
            app.TesteEditField.Value = 15;
            app.CarregarImagemButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            app.TestarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.TestarButton.ButtonPushedFcn = createCallbackFcn(app, @TestarButtonPushed, true);
            app.TestarButton.Enable = 'off';
            app.TestarButton.Text = 'Testar';
            app.UIAxes = uiaxes(app.RedesNeuronaisGUIUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.RedesNeuronaisGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = alineaE

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RedesNeuronaisGUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RedesNeuronaisGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RedesNeuronaisGUIUIFigure)
        end
    end
end