classdef nn_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FicheiroMenu                    matlab.ui.container.Menu
        GravarRedeMenu                  matlab.ui.container.Menu
        CarregarRedeMenu                matlab.ui.container.Menu
        ConfiguraodaRedePanel           matlab.ui.container.Panel
        TopologiaDropDownLabel          matlab.ui.control.Label
        TopologiaDropDown               matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel       matlab.ui.control.Label
        FuncaoTreinoDropDown            matlab.ui.control.DropDown
        NumerodecamadasescondidasEditFieldLabel  matlab.ui.control.Label
        NumeroCamadasEscondidasEditField  matlab.ui.control.NumericEditField
        TreinarRedeButton               matlab.ui.control.Button
        pocasEditFieldLabel             matlab.ui.control.Label
        EpocasEditField                 matlab.ui.control.NumericEditField
        PropriedadesdasCamadasPanel     matlab.ui.container.Panel
        CamadaDropDownLabel             matlab.ui.control.Label
        CamadaDropDown                  matlab.ui.control.DropDown
        NumerodeneurniosEditFieldLabel  matlab.ui.control.Label
        NumeroNeurniosEditField         matlab.ui.control.NumericEditField
        FunodeAtivaoDropDownLabel       matlab.ui.control.Label
        FuncaoAtivaoDropDown            matlab.ui.control.DropDown
        ValidarButton                   matlab.ui.control.Button
        ImagensEditFieldLabel           matlab.ui.control.Label
        ImagensPathEditField            matlab.ui.control.EditField
        ProcurarButton                  matlab.ui.control.Button
        CarregarImagemButton            matlab.ui.control.Button
        TestarRedeButton                matlab.ui.control.Button
        ResultadoLabel                  matlab.ui.control.Label
        SegmentacaodoDatasetPanel       matlab.ui.container.Panel
        TreinoEditFieldLabel            matlab.ui.control.Label
        TreinoEditField                 matlab.ui.control.NumericEditField
        ValidacaoEditFieldLabel         matlab.ui.control.Label
        ValidacaoEditField              matlab.ui.control.NumericEditField
        TesteEditFieldLabel             matlab.ui.control.Label
        TesteEditField                  matlab.ui.control.NumericEditField
        GravarDadosButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Topologia;
        FuncaoTreino;
        NumeroCamadas;
        Camadas;
        Net;
        Path;
        
        Input;
        Target;
        
        Imagem;
        
        Epocas;
        
        Treino;
        Validacao;
        Teste;
        
        Out;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Topologia = app.TopologiaDropDown.Value;
            app.FuncaoTreino = app.FuncaoTreinoDropDown.Value;
            app.NumeroCamadas = app.NumeroCamadasEscondidasEditField.Value + 1;
            app.Camadas = cell(1, app.NumeroCamadas, 2);
            app.CamadaDropDown.Items = {'Camada 1', 'Camada OUTPUT'};
            app.CamadaDropDown.Value = string(app.CamadaDropDown.Items(1));
            app.CamadaDropDown.ItemsData = 1:length(app.CamadaDropDown.Items);
            value = app.CamadaDropDown.Value;
            app.Camadas(1, value, 1) = {app.NumeroNeurniosEditField.Value};
            app.Camadas(1, value, 2) = {app.FuncaoAtivaoDropDown.Value};
            app.Path = "";
            
            app.Treino = 0.8;
            app.TreinoEditField.Value = app.Treino;
            app.Validacao = 0.1;
            app.ValidacaoEditField.Value = app.Validacao;
            app.Teste = 0.1;
            app.TesteEditField.Value = app.Teste;
            app.Epocas = 1000;
            app.EpocasEditField.Value = app.Epocas;            
        end

        % Value changed function: NumeroCamadasEscondidasEditField
        function NumeroCamadasEscondidasEditFieldValueChanged(app, event)
            value = app.NumeroCamadasEscondidasEditField.Value;
            app.CamadaDropDown.Items = {sprintf('Camada %.0f', 1)};
            if value > 1
                for i = 2 : value
                    app.CamadaDropDown.Items = [app.CamadaDropDown.Items sprintf('Camada %.0f', i)];
                end
            end
            app.CamadaDropDown.Items = [app.CamadaDropDown.Items sprintf('Camada OUTPUT')];
            app.CamadaDropDown.ItemsData = 1:length(app.CamadaDropDown.Items);
            value = value + 1;
            app.NumeroCamadas = value;
            app.Camadas = cell(1, value, 2);
        end

        % Value changed function: TopologiaDropDown
        function TopologiaDropDownValueChanged(app, event)
            app.Topologia = app.TopologiaDropDown.Value;
        end

        % Value changed function: FuncaoTreinoDropDown
        function FuncaoTreinoDropDownValueChanged(app, event)
            app.FuncaoTreino = app.FuncaoTreinoDropDown.Value;
        end

        % Button pushed function: ValidarButton
        function ValidarButtonPushed(app, event)
            app.CamadaDropDown.ItemsData = 1:length(app.CamadaDropDown.Items);
            value = app.CamadaDropDown.Value;
            app.Camadas(1, value, 1) = {app.NumeroNeurniosEditField.Value};
            app.Camadas(1, value, 2) = {app.FuncaoAtivaoDropDown.Value};
        end

        % Button pushed function: TreinarRedeButton
        function TreinarRedeButtonPushed(app, event)
            app.GravarDadosButton.Enable = 'off';
            
            try
            % app.Net = carregaRede(app.Topologia, app.Camadas, app.NumeroCamadas, app.FuncaoTreino, app.Path);
            tam = app.NumeroCamadas - 1;
            
            neuronios = zeros(1, tam);
            dados = cell2mat(app.Camadas(1, 1:app.NumeroCamadas, 1));
            
            for i = 1 : tam
                neuronios(i) = dados(i);
            end
            
            switch(app.Topologia)
                case "FeedForwardNet"
                    app.Net = feedforwardnet(neuronios);
                case "CascadeNet"
                    app.Net = cascadeforwardnet(neuronios);
                case "PatternNet"
                    app.Net = patternnet(neuronios);
                case "FitNet"
                    app.Net = fitnet(neuronios);
            end
            
            for i = 1: app.NumeroCamadas
                app.Net.layers{i}.transferFcn = string(app.Camadas(1, i, 2));
            end
            
            % Funcao de Treino da Rede
            app.Net.trainFcn = app.FuncaoTreino;
            
            if app.Epocas == 0
                app.Epocas = 1000;
                app.EpocasEditField.Value = 1000;
            end
            app.Net.trainParam.epochs = app.Epocas;
            
            % Segmentacao
            if(app.Teste + app.Validacao + app.Treino < 1)
                app.Net.divideFcn = '';
                msgbox('Não foram introduzidos os valores de segmentação adequados', 'Error','error');
                return;
            end
            app.Net.divideFcn = 'dividerand';
            app.Net.divideParam.trainRatio = app.Treino;
            app.Net.divideParam.valRatio = app.Validacao;
            app.Net.divideParam.testRatio = app.Teste;
           
            [app.Input, tamanho] = tratamento_imagens(app.Path);
            app.Target = gera_target(tamanho);
            
            app.Net = train(app.Net, app.Input, app.Target);
            
            catch e
                msgbox('Não possui os argumentos necessários carregados', 'Error','error');
            end
        end

        % Button pushed function: ProcurarButton
        function ProcurarButtonPushed(app, event)
            path = uigetdir("../res/img/", "Select Image Folder");
            if(path ~= 0)
                app.Path = path;
                app.ImagensPathEditField.Value = path; 
            end
        end

        % Value changed function: CamadaDropDown
        function CamadaDropDownValueChanged(app, event)
            value = app.CamadaDropDown.Value;
            disp(value);
            disp(app.NumeroCamadas);
            if value == app.NumeroCamadas
                app.NumeroNeurniosEditField.Enable = 'Off';
            else
                app.NumeroNeurniosEditField.Enable = 'On';
            end
        end

        % Menu selected function: GravarRedeMenu
        function GravarRedeMenuSelected(app, event)
            if(~isempty(app.Net))
                [file, path] = uiputfile({'*.mat'}, "Save net", "net.mat");
                if(path ~= 0)
                   net = app.Net;
                   save(sprintf("%s/%s", path, file), 'net');
                end
            else
                msgbox('Não existe nenhuma rede para ser guardada', 'Error','error');
            end
        end

        % Menu selected function: CarregarRedeMenu
        function CarregarRedeMenuSelected(app, event)
            [name, path] = uigetfile('*.mat');
            if(path ~= 0)
                ficheiro = string(sprintf("%s/%s", path, name));
                load(ficheiro, 'net');
                app.Net = net;
            end
        end

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            [file, path] = uigetfile('*.jpg');
            if(path ~= 0)
                ficheiro = sprintf("%s/%s", path, file);
                app.Imagem = imread(ficheiro, "jpg");
                app.Imagem = app.Imagem(:,:,1);
                imshow(app.Imagem, 'parent', app.UIAxes);
                app.Imagem = imresize(app.Imagem, [32 32]);
                app.Imagem = imbinarize(app.Imagem);
                app.Imagem = app.Imagem(:);
                app.ResultadoLabel.Text = '';
                app.GravarDadosButton.Enable = 'off';
            end
        end

        % Button pushed function: TestarRedeButton
        function TestarRedeButtonPushed(app, event)
            out = sim(app.Net, app.Imagem);
            disp(out);
            [M, i] = max(out);
            app.ResultadoLabel.Text = sprintf(...
            "A imagem introduzida é considerada a letra %d com uma precisão de %2.2f%%",...
            i, (M * 100));
            app.GravarDadosButton.Enable = 'on';
            app.Out = out;
        end

        % Value changed function: TreinoEditField
        function TreinoEditFieldValueChanged(app, event)
            value = app.TreinoEditField.Value;
            if value + app.TesteEditField.Value + app.ValidacaoEditField.Value > 1
                app.TreinoEditField.Value = 1 - (app.TesteEditField.Value + app.ValidacaoEditField.Value);
            end
            app.Treino = app.TreinoEditField.Value;
        end

        % Value changed function: ValidacaoEditField
        function ValidacaoEditFieldValueChanged(app, event)
            value = app.ValidacaoEditField.Value;
            if value + app.TesteEditField.Value + app.TreinoEditField.Value > 1
                app.ValidacaoEditField.Value = 1 - (app.TesteEditField.Value + app.TreinoEditField.Value);
            end
            app.Validacao = app.ValidacaoEditField.Value;
        end

        % Value changed function: TesteEditField
        function TesteEditFieldValueChanged(app, event)
            value = app.TesteEditField.Value;
            if value + app.ValidacaoEditField.Value + app.TreinoEditField.Value > 1
                app.TesteEditField.Value = 1 - (app.ValidacaoEditField.Value + app.TreinoEditField.Value);
            end
            app.Teste = app.TesteEditField.Value;
        end

        % Value changed function: EpocasEditField
        function EpocasEditFieldValueChanged(app, event)
            app.Epocas = app.EpocasEditField.Value;
        end

        % Button pushed function: GravarDadosButton
        function GravarDadosButtonPushed(app, event)
            ck = clock;
            filename = sprintf("resultados_%d_%d_%d_%d:%d.xlsx", ck(1), ck(2), ck(3), ck(4), ck(5));
            
            tabela = cell(6 + app.Net.numLayers, 3);
            tabela(1, 1) = {'Nome:'};
            tabela(1, 2) = {app.Net.name};
            tabela(2, 1) = {app.Net.divideFcn};
            tabela(3, 1) = {'Treino'};
            tabela(3, 2) = {app.Net.divideParam.trainRatio};
            tabela(4, 1) = {'Validacao'};
            tabela(4, 2) = {app.Net.divideParam.valRatio};
            tabela(5, 1) = {'Teste'};
            tabela(5, 2) = {app.Net.divideParam.testRatio};
            tabela(6, 1) = {'Camada'};
            tabela(6, 2) = {'Funcao'};
            tabela(6, 3) = {'Neuronios'};
            for i = 1 : app.Net.numLayers
                tabela(6+i, 1) = {app.Net.layers{i}.name};
                tabela(6+i, 2) = {app.Net.layers{i}.transferFcn};
                tabela(6+i, 3) = {app.Net.layers{i}.size};
            end
            T = cell2table(tabela, "VariableNames", {' ', '  ', '   '});
            writetable(T, filename, 'Sheet', 1);
            out = app.Out;
            A = table(out);
            writetable(A, filename, 'Sheet', 1, 'Range', 'F2:F500');
            writecell({app.ResultadoLabel.Text}, filename, 'Sheet', 1, 'Range', sprintf("F%d",...
                length(app.Out) + 5));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FicheiroMenu = uimenu(app.UIFigure);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.GravarRedeMenu = uimenu(app.FicheiroMenu);
            app.GravarRedeMenu.MenuSelectedFcn = createCallbackFcn(app, @GravarRedeMenuSelected, true);
            app.GravarRedeMenu.Text = 'Gravar Rede';
            app.CarregarRedeMenu = uimenu(app.FicheiroMenu);
            app.CarregarRedeMenu.MenuSelectedFcn = createCallbackFcn(app, @CarregarRedeMenuSelected, true);
            app.CarregarRedeMenu.Text = 'Carregar Rede';
            app.ConfiguraodaRedePanel = uipanel(app.UIFigure);
            app.ConfiguraodaRedePanel.Title = 'Configuração da Rede';
            app.TopologiaDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.TopologiaDropDownLabel.Text = 'Topologia';
            app.TopologiaDropDown = uidropdown(app.ConfiguraodaRedePanel);
            app.TopologiaDropDown.Items = {'FeedForwardNet', 'CascadeNet', 'PatternNet', 'FitNet'};
            app.TopologiaDropDown.ValueChangedFcn = createCallbackFcn(app, @TopologiaDropDownValueChanged, true);
            app.TopologiaDropDown.Value = 'FeedForwardNet';
            app.FunodeTreinoDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.FuncaoTreinoDropDown = uidropdown(app.ConfiguraodaRedePanel);
            app.FuncaoTreinoDropDown.Items = {'trainlm', 'trainrp', 'traingd', 'trainoss', 'trainscg'};
            app.FuncaoTreinoDropDown.ValueChangedFcn = createCallbackFcn(app, @FuncaoTreinoDropDownValueChanged, true);
            app.FuncaoTreinoDropDown.Value = 'trainlm';
            app.NumerodecamadasescondidasEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.NumerodecamadasescondidasEditFieldLabel.Text = 'Numero de camadas escondidas';
            app.NumeroCamadasEscondidasEditField = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.NumeroCamadasEscondidasEditField.Limits = [1 Inf];
            app.NumeroCamadasEscondidasEditField.RoundFractionalValues = 'on';
            app.NumeroCamadasEscondidasEditField.ValueDisplayFormat = '%.0f';
            app.NumeroCamadasEscondidasEditField.ValueChangedFcn = createCallbackFcn(app, @NumeroCamadasEscondidasEditFieldValueChanged, true);
            app.NumeroCamadasEscondidasEditField.Value = 1;
            app.TreinarRedeButton = uibutton(app.ConfiguraodaRedePanel, 'push');
            app.TreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRedeButton.Text = 'Treinar Rede';
            app.pocasEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.pocasEditFieldLabel.Text = 'Épocas';
            app.EpocasEditField = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.EpocasEditField.Limits = [1 Inf];
            app.EpocasEditField.ValueChangedFcn = createCallbackFcn(app, @EpocasEditFieldValueChanged, true);
            app.EpocasEditField.Value = 1;
            app.PropriedadesdasCamadasPanel = uipanel(app.UIFigure);
            app.PropriedadesdasCamadasPanel.Title = 'Propriedades das Camadas';
            app.CamadaDropDownLabel = uilabel(app.PropriedadesdasCamadasPanel);
            app.CamadaDropDownLabel.Text = 'Camada';
            app.CamadaDropDown = uidropdown(app.PropriedadesdasCamadasPanel);
            app.CamadaDropDown.Items = {'Camada 1'};
            app.CamadaDropDown.ValueChangedFcn = createCallbackFcn(app, @CamadaDropDownValueChanged, true);
            app.CamadaDropDown.Value = 'Camada 1';
            app.NumerodeneurniosEditFieldLabel = uilabel(app.PropriedadesdasCamadasPanel);
            app.NumerodeneurniosEditFieldLabel.Text = 'Numero de neurónios';
            app.NumeroNeurniosEditField = uieditfield(app.PropriedadesdasCamadasPanel, 'numeric');
            app.NumeroNeurniosEditField.Limits = [1 Inf];
            app.NumeroNeurniosEditField.RoundFractionalValues = 'on';
            app.NumeroNeurniosEditField.ValueDisplayFormat = '%.0f';
            app.NumeroNeurniosEditField.Value = 1;
            app.FunodeAtivaoDropDownLabel = uilabel(app.PropriedadesdasCamadasPanel);
            app.FunodeAtivaoDropDownLabel.Text = 'Função de Ativação';
            app.FuncaoAtivaoDropDown = uidropdown(app.PropriedadesdasCamadasPanel);
            app.FuncaoAtivaoDropDown.Items = {'tansig', 'purelin', 'logsig', 'radbasn'};
            app.FuncaoAtivaoDropDown.Value = 'tansig';
            app.ValidarButton = uibutton(app.PropriedadesdasCamadasPanel, 'push');
            app.ValidarButton.ButtonPushedFcn = createCallbackFcn(app, @ValidarButtonPushed, true);
            app.ValidarButton.Text = 'Validar';
            app.ImagensEditFieldLabel = uilabel(app.UIFigure);
            app.ImagensEditFieldLabel.Text = 'Imagens';
            app.ImagensPathEditField = uieditfield(app.UIFigure, 'text');
            app.ProcurarButton = uibutton(app.UIFigure, 'push');
            app.ProcurarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcurarButtonPushed, true);
            app.ProcurarButton.Text = 'Procurar';
            app.CarregarImagemButton = uibutton(app.UIFigure, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            app.TestarRedeButton = uibutton(app.UIFigure, 'push');
            app.TestarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TestarRedeButtonPushed, true);
            app.TestarRedeButton.Text = 'Testar Rede';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = '';
            app.SegmentacaodoDatasetPanel = uipanel(app.UIFigure);
            app.SegmentacaodoDatasetPanel.Title = 'Segmentacao do Dataset';
            app.TreinoEditFieldLabel = uilabel(app.SegmentacaodoDatasetPanel);
            app.TreinoEditFieldLabel.Text = 'Treino';
            app.TreinoEditField = uieditfield(app.SegmentacaodoDatasetPanel, 'numeric');
            app.TreinoEditField.Limits = [0 1];
            app.TreinoEditField.ValueChangedFcn = createCallbackFcn(app, @TreinoEditFieldValueChanged, true);
            app.ValidacaoEditFieldLabel = uilabel(app.SegmentacaodoDatasetPanel);
            app.ValidacaoEditFieldLabel.Text = 'Validacao';
            app.ValidacaoEditField = uieditfield(app.SegmentacaodoDatasetPanel, 'numeric');
            app.ValidacaoEditField.Limits = [0 1];
            app.ValidacaoEditField.ValueChangedFcn = createCallbackFcn(app, @ValidacaoEditFieldValueChanged, true);
            app.TesteEditFieldLabel = uilabel(app.SegmentacaodoDatasetPanel);
            app.TesteEditFieldLabel.Text = 'Teste';
            app.TesteEditField = uieditfield(app.SegmentacaodoDatasetPanel, 'numeric');
            app.TesteEditField.Limits = [0 1];
            app.TesteEditField.ValueChangedFcn = createCallbackFcn(app, @TesteEditFieldValueChanged, true);
            app.GravarDadosButton = uibutton(app.UIFigure, 'push');
            app.GravarDadosButton.ButtonPushedFcn = createCallbackFcn(app, @GravarDadosButtonPushed, true);
            app.GravarDadosButton.Enable = 'off';
            app.GravarDadosButton.Text = 'Gravar Dados';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nn_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end