classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RedesNeuronaisGUIUIFigure       matlab.ui.Figure
        FunodeTreinoDropDownLabel       matlab.ui.control.Label
        FunodeTreinoDropDown            matlab.ui.control.DropDown
        RedesNeuronaisLabel             matlab.ui.control.Label
        TrabalhoPrticodeCRLabel         matlab.ui.control.Label
        FunodeAtivaoDropDownLabel       matlab.ui.control.Label
        FunodeAtivaoDropDown            matlab.ui.control.DropDown
        FunodeDivisoDropDownLabel       matlab.ui.control.Label
        FunodeDivisoDropDown            matlab.ui.control.DropDown
        TreinarButton                   matlab.ui.control.Button
        GuardarButton                   matlab.ui.control.Button
        CarregarButton                  matlab.ui.control.Button
        pocasSliderLabel                matlab.ui.control.Label
        pocasSlider                     matlab.ui.control.Slider
        MatrizdeConfusoButton           matlab.ui.control.Button
        Mdiadeprecisonosexemplos100Label  matlab.ui.control.Label
        Mdiadeprecisonostestes100Label  matlab.ui.control.Label
        SimulaesSliderLabel             matlab.ui.control.Label
        SimulaesSlider                  matlab.ui.control.Slider
        TopologiaDropDownLabel          matlab.ui.control.Label
        TopologiaDropDown               matlab.ui.control.DropDown
        PastadasImagensEditFieldLabel   matlab.ui.control.Label
        PastadasImagensEditField        matlab.ui.control.EditField
        LimparButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Net % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
            folder = app.PastadasImagensEditField.Value;
            [input, tamanho] = process_images(folder);
            target = gen_target(tamanho);
            
            accuracyFinalTeste = 0;
            accuracyFinalExemplos = 0;
            nSim = app.SimulaesSlider.Value;
            
            type = app.TopologiaDropDown.Value;
            transferFcn = app.FunodeAtivaoDropDown.Value;
            trainFcn = app.FunodeTreinoDropDown.Value;
            divideFcn = app.FunodeDivisoDropDown.Value;
            epochs = app.pocasSlider.Value;
            
            net = loadNet(type, transferFcn, trainFcn, divideFcn, epochs);
            app.TreinarButton.Enable = 'false';
            [net, tr] =  train(net, input, target);
            app.TreinarButton.Enable = 'true';
            plot(app.UIAxes);
            view(net);
        end

        % Button pushed function: CarregarButton
        function CarregarButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if(path ~= 0)
                cd(path);
                load(file, "net");
                app.Net = net;
            end

            app.TopologiaDropDown.Value = "Feedforward (10 neurónios)";
            app.FunodeAtivaoDropDown.Value = "logsig";
            app.FunodeTreinoDropDown.Value = app.Net.trainFcn;
            app.FunodeDivisoDropDown.Value = app.Net.divideFcn;
            app.pocasSlider.Value = app.Net.trainParam.epochs;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RedesNeuronaisGUIUIFigure = uifigure('Visible', 'off');
            app.RedesNeuronaisGUIUIFigure.Name = 'Redes Neuronais - GUI';
            app.FunodeTreinoDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.FunodeTreinoDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.FunodeTreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainscg', 'trainoss', 'traingdx', 'traingd'};
            app.FunodeTreinoDropDown.Value = 'trainlm';
            app.RedesNeuronaisLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.RedesNeuronaisLabel.Text = 'Redes Neuronais';
            app.TrabalhoPrticodeCRLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.TrabalhoPrticodeCRLabel.Text = 'Trabalho Prático de CR';
            app.FunodeAtivaoDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.FunodeAtivaoDropDownLabel.Text = 'Função de Ativação';
            app.FunodeAtivaoDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.FunodeAtivaoDropDown.Items = {'logsig', 'hardlim', 'purelin', 'softmax', 'tansig', 'tribas'};
            app.FunodeAtivaoDropDown.Value = 'logsig';
            app.FunodeDivisoDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.FunodeDivisoDropDownLabel.Text = 'Função de Divisão';
            app.FunodeDivisoDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.FunodeDivisoDropDown.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.FunodeDivisoDropDown.Value = 'dividerand';
            app.TreinarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Text = 'Treinar';
            app.GuardarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.GuardarButton.Enable = 'off';
            app.GuardarButton.Text = 'Guardar';
            app.CarregarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.CarregarButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarButtonPushed, true);
            app.CarregarButton.Text = 'Carregar';
            app.pocasSliderLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.pocasSliderLabel.Text = 'Épocas';
            app.pocasSlider = uislider(app.RedesNeuronaisGUIUIFigure);
            app.pocasSlider.Limits = [0 1000];
            app.pocasSlider.Value = 1000;
            app.MatrizdeConfusoButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.MatrizdeConfusoButton.Text = 'Matriz de Confusão';
            app.Mdiadeprecisonosexemplos100Label = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.Mdiadeprecisonosexemplos100Label.Text = 'Média de precisão nos exemplos: 100%';
            app.Mdiadeprecisonostestes100Label = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.Mdiadeprecisonostestes100Label.Text = 'Média de precisão nos testes: 100%';
            app.SimulaesSliderLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.SimulaesSliderLabel.Text = 'Simulações';
            app.SimulaesSlider = uislider(app.RedesNeuronaisGUIUIFigure);
            app.SimulaesSlider.Limits = [0 10];
            app.SimulaesSlider.Value = 5;
            app.TopologiaDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.TopologiaDropDownLabel.Text = 'Topologia';
            app.TopologiaDropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.TopologiaDropDown.Items = {'Feedforward (10 neurónios)', 'Pattern (10 neurónios)', 'Fitting (10 neurónios)'};
            app.TopologiaDropDown.Value = 'Feedforward (10 neurónios)';
            app.PastadasImagensEditFieldLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.PastadasImagensEditFieldLabel.Text = 'Pasta das Imagens';
            app.PastadasImagensEditField = uieditfield(app.RedesNeuronaisGUIUIFigure, 'text');
            app.PastadasImagensEditField.Value = 'imagens/train/*/*.png';
            app.LimparButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.LimparButton.Text = 'Limpar';
            app.UIAxes = uiaxes(app.RedesNeuronaisGUIUIFigure);
            title(app.UIAxes, 'Performance')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.RedesNeuronaisGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RedesNeuronaisGUIUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.RedesNeuronaisGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RedesNeuronaisGUIUIFigure)
        end
    end
end